classdef Digital_Logic_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputBDropDown              matlab.ui.control.DropDown
        InputBDropDownLabel         matlab.ui.control.Label
        InputADropDown              matlab.ui.control.DropDown
        InputADropDownLabel         matlab.ui.control.Label
        BooleanExpressionLabel      matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        ResetButton                 matlab.ui.control.Button
        GenerateTruthtableButton    matlab.ui.control.Button
        SimulateButton              matlab.ui.control.Button
        OutputLabelTextArea         matlab.ui.control.TextArea
        GateDropDown                matlab.ui.control.DropDown
        OutputLabel                 matlab.ui.control.Label
        LogicGateLabel              matlab.ui.control.Label
        DigitalLogicSimulatorLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


    % Read image
    img = imread('bg.jpg');

    % Convert to UIFigure background
    fig = app.UIFigure;

    % Create background axes (hidden)
    bgAx = axes(fig);
    bgAx.Units = 'normalized';
    bgAx.Position = [0 0 1 1];

    % Show image
    imshow(img, 'Parent', bgAx);

    % Remove everything
    bgAx.Visible = 'off';

    % Send to back
    uistack(bgAx, 'bottom');

               
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            A = str2double(app.InputADropDown.Value);
            B = str2double(app.InputBDropDown.Value);
            gate = app.GateDropDown.Value;
            switch gate
                case 'AND'
                    Y = A & B;
                case 'OR'
                    Y = A | B;
                case 'NAND'
                    Y = ~(A & B);
                case 'NOR'
                    Y = ~(A | B);
                case 'XOR'
                    Y = xor(A,B);
                case 'XNOR'
                    Y = ~xor(A,B);
                case 'NOT'
                    Y = ~A;
            end
            app.OutputLabelTextArea.Value = string(Y);
               
        end

        % Button pushed function: GenerateTruthtableButton
        function GenerateTruthtableButtonPushed(app, event)
            gate = app.GateDropDown.Value;
            if strcmp(gate,'AND')
                app.UITable.Data = [
                    0 0 0;
                    0 1 0;
                    1 0 0;
                    1 1 1
                    ];

            elseif strcmp(gate,'OR')
                app.UITable.Data = [
                    0 0 0;
                    0 1 1;
                    1 0 1;
                    1 1 1
                    ];

            elseif strcmp(gate,'NAND')
                app.UITable.Data = [
                    0 0 1;
                    0 1 1;
                    1 0 1;
                    1 1 0
                    ];
            elseif strcmp(gate,'NOR')
                app.UITable.Data = [
                    0 0 1;
                    0 1 0;
                    1 0 0;
                    1 1 0
                    ];
            elseif strcmp(gate,'XOR')
                app.UITable.Data = [
                    0 0 0;
                    0 1 1;
                    1 0 1;
                    1 1 0
                    ];
            elseif strcmp(gate,'XNOR')
                app.UITable.Data = [
                    0 0 0;
                    0 1 1;
                    1 0 1;
                    1 1 1
                    ];
            elseif strcmp(gate,'NOT')
                app.UITable.Data = [
                    0 1 ;                    
                    1 0  
                    ];

            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.GateDropDown.Value = 'AND';
            app.InputADropDown.Value = '0';
            app.InputBDropDown.Value = '0';

            app.OutputLabelTextArea.Value = "";

            app.UITable.Data = [];
        end

        % Value changed function: GateDropDown
        function GateDropDownValueChanged(app, event)
             gate = app.GateDropDown.Value;

                % ----------------------------
                % INPUT B ENABLE / DISABLE
                % ----------------------------
                if strcmp(gate,'NOT')
                    app.InputBDropDown.Value = '0';
                    app.InputBDropDown.Enable = 'off';
                else
                    app.InputBDropDown.Enable = 'on';
                end

                % ----------------------------
                % BOOLEAN EXPRESSION LOGIC
                % ----------------------------
                switch gate

                    case 'AND'
                        expr = 'Y = A · B';

                    case 'OR'
                        expr = 'Y = A + B';

                    case 'NOT'
                        expr = 'Y = A''';

                    case 'XOR'
                        expr = 'Y = A ⊕ B';

                    case 'NAND'
                        expr = 'Y = (A · B)''';

                    case 'NOR'
                        expr = 'Y = (A + B)''';

                    otherwise
                        expr = 'Expression not defined';

                end

                % ----------------------------
                % DISPLAY BOOLEAN EXPRESSION
                % ----------------------------
                app.BooleanExpressionLabel.Text = expr;

           
        end

        % Callback function
        function UIAxesButtonDown(app, event)
         
        end

        % Callback function
        function UIAxesButtonDown2(app, event)
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'sky');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bg.jpg');
            app.DigitalLogicSimulatorLabel = uilabel(app.UIFigure);
            app.DigitalLogicSimulatorLabel.Text = 'INTERACTIVE DIGITAL LOGIC CIRCUIT SIMULATOR';
            app.LogicGateLabel = uilabel(app.UIFigure);
            app.LogicGateLabel.Text = 'Logic Gate :';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output :';
            app.GateDropDown = uidropdown(app.UIFigure);
            app.GateDropDown.Items = {'AND', 'OR', 'NOT', 'NAND', 'NOR', 'XOR', 'XNOR'};
            app.GateDropDown.ValueChangedFcn = createCallbackFcn(app, @GateDropDownValueChanged, true);
            app.GateDropDown.Value = 'AND';
            app.OutputLabelTextArea = uitextarea(app.UIFigure);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.GenerateTruthtableButton = uibutton(app.UIFigure, 'push');
            app.GenerateTruthtableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTruthtableButtonPushed, true);
            app.GenerateTruthtableButton.Text = 'Generate Truth table';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'A'; 'B'; 'Y'};
            app.UITable.RowName = {};
            app.BooleanExpressionLabel = uilabel(app.UIFigure);
            app.BooleanExpressionLabel.Text = 'Boolean Expression';
            app.InputADropDownLabel = uilabel(app.UIFigure);
            app.InputADropDownLabel.Text = 'Input A : ';
            app.InputADropDown = uidropdown(app.UIFigure);
            app.InputADropDown.Items = {'0', '1'};
            app.InputADropDown.Value = '0';
            app.InputBDropDownLabel = uilabel(app.UIFigure);
            app.InputBDropDownLabel.Text = 'Input B : ';
            app.InputBDropDown = uidropdown(app.UIFigure);
            app.InputBDropDown.Items = {'0', '1'};
            app.InputBDropDown.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Digital_Logic_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end